classdef NAEGroup5_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ByLabel             matlab.ui.control.Label
        SpaceDebrisandObjectsinLEOLabel  matlab.ui.control.Label
        Label_6             matlab.ui.control.Label
        Label_5             matlab.ui.control.Label
        Label_4             matlab.ui.control.Label
        EditField_12        matlab.ui.control.EditField
        EditField_11        matlab.ui.control.EditField
        EditField_10        matlab.ui.control.EditField
        EditField_6         matlab.ui.control.EditField
        EditField_5         matlab.ui.control.EditField
        EditField_4         matlab.ui.control.EditField
        TotalRB             matlab.ui.control.Label
        TotalDEB            matlab.ui.control.Label
        TotalPAY            matlab.ui.control.Label
        TextArea            matlab.ui.control.TextArea
        SendMetoAppButton   matlab.ui.control.Button
        HelpTextArea        matlab.ui.control.TextArea
        HelpButton_2        matlab.ui.control.Button
        HelpButton          matlab.ui.control.Button
        EndSlider           matlab.ui.control.Slider
        EndSliderLabel      matlab.ui.control.Label
        PlotButton          matlab.ui.control.Button
        InitialSlider       matlab.ui.control.Slider
        InitialSliderLabel  matlab.ui.control.Label
        PayloadButton       matlab.ui.control.Button
        RocketBodyButton    matlab.ui.control.Button
        DebrisButton        matlab.ui.control.Button
        Image               matlab.ui.control.Image
        GlobeAxes           matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    chinaYears = readmatrix("CHINA_DRP.csv")
    russiaYears = readmatrix("RUSSIA_DRP.csv")
    usYears = readmatrix("US_DRP.csv") 
    %variables to use for globe
    DebrisTotal
    RocketBodyTotal
    PayloadTotal
    %Globe data
    DecayGlobeUS = readmatrix("US_DECAY_DRP.csv")
    DecayGlobeChina = readmatrix("CHINA_DECAY_DRP.csv")
    DecayGlobeRussia = readmatrix("RUSSIA_DECAY_DRP.csv")
    %background image
    Space=imread('Space2.jpg')

    %x values 
    STARTLIM = 0;
    ENDLIM = 0; 
    total_objects
    tot_d
    tot_rb
    tot_pay
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.UIFigure.WindowState = 'maximized';
            app.TextArea.Value = 'In regard to space technologies and missions, the current opportunities of space exploration are limitless. However, a major obstacle space exploration is facing is the growth of orbital debris in Low Earth Orbit (LEO) due to several factors: the launching into orbit and abandonment of spent satellites, in-flight collisions, and a process known as fragmentation.';                                                                                                                                                                                                    
            app.TextArea.Value = [app.TextArea.Value;' ';'This app aims at providing satellite data over 25 years, starting in 1997 until 2022, based on the three countries that have had the largest contributions; China, Russia, and the US. You will be able to see how many space objects are in space within a specific date range and just how much each country has contributed to the over population of LEO. '];
            app.TextArea.Value = [app.TextArea.Value;' ' ;'Use the two year sliders to set a range of years and press the "Plot" button to see how many objects are in space during your set range. The globe will populate with the total amount of objects in space, and the line graph will display the total amount of debris, rocket bodies, and payloads (operational satellites) from the three major countries.  The bar graph below will display data for each country, when you press on either of the three buttons. The three buttons are used to categorize each of the objects, so you can see exactly how much each country is contributing to a specific object type in space.'];
            app.HelpButton_2.Visible='off';
            app.HelpButton.Visible="off";
            app.TotalDEB.Visible = "off";
            app.TotalPAY.Visible = "off";
            app.TotalRB.Visible = "off";
            app.Label_4.Visible = "off";
            app.Label_5.Visible = "off";
            app.Label_6.Visible = "off";
            app.EditField_4.Visible = "off";
            app.EditField_5.Visible = "off";
            app.EditField_6.Visible = "off";
            app.EditField_10.Visible = "off";
            app.EditField_11.Visible = "off";
            app.EditField_12.Visible = "off";
            %app.Label.Visible = "off";
            %app.Label_2.Visible = "off";
            app.EndSlider.Visible="off";
            app.EndSliderLabel.Visible="off";
            app.TotalDEB.Visible = "off";
            app.TotalRB.Visible = "off";
            app.TotalPAY.Visible = "off";
            app.UIAxes.Visible="off";
            app.UIAxes2.Visible="off";
            app.PayloadButton.Visible="off";
            app.DebrisButton.Visible='off';
            app.PayloadButton.Visible="off";
            app.HelpTextArea.Visible='off';
            app.PlotButton.Visible='off';
            app.InitialSlider.Visible='off';
            app.InitialSliderLabel.Visible="off";
            app.RocketBodyButton.Visible="off";
            

            %Change background 
            set(app.UIAxes2,'Color','k');
            set(app.UIAxes, 'Color', 'k');
            set(app.GlobeAxes,'Color', 'k');
            app.UIFigure.Color = 'Black';
            app.EndSlider.Value = 2022;
        end

        % Button pushed function: DebrisButton
        function DebrisButtonPushed(app, event)
            %varaibles with debris are assigned to all satellites for that country
            c_temp = app.chinaYears(:,2);
            r_temp = app.russiaYears(:,2);
            u_temp = app.usYears(:,2);
            year = app.InitialSlider.Value;
            year2 = app.EndSlider.Value;
            %find specific year in each debris variable 
            c_debris1 = sum(c_temp >= year & c_temp <= year2); 
            r_debris1 = sum(r_temp >= year & r_temp <= year2);
            u_debris1 = sum(u_temp >= year & u_temp <= year2);
            app.DebrisTotal = c_debris1 + r_debris1 + u_debris1;
            X = categorical({'China','Russia','US'});
            Y = [c_debris1 r_debris1 u_debris1];
            b = bar(app.UIAxes, X, Y, 'FaceColor', 'flat');
            b.CData = [0 0 1; 0 1 0; 1 0 0];
            for i=1:1:length(X)
            text(app.UIAxes, i:i,Y(i)',num2str(Y(i)','%0.0f'),'HorizontalAlignment','center','VerticalAlignment','bottom','Color','w')
            end
        end

        % Button pushed function: RocketBodyButton
        function RocketBodyButtonPushed(app, event)
            %varaibles with Rocket Body are assigned to all satellites for that country
            c_temp = app.chinaYears(:,4);
            r_temp = app.russiaYears(:,4);
            u_temp = app.usYears(:,4);
            year = app.InitialSlider.Value;
            year2 = app.EndSlider.Value;
            %find specific year in each debris variable 
            c_rb = sum(c_temp >= year & c_temp <= year2); 
            r_rb= sum(r_temp >= year & r_temp <= year2); 
            u_rb = sum(u_temp >= year & u_temp <= year2);
            app.RocketBodyTotal = c_rb + r_rb + u_rb ;
            X = categorical({'China','Russia','US'});
            Y = [c_rb r_rb u_rb];
            b = bar(app.UIAxes, X, Y, 'FaceColor', 'flat');
            b.CData = [0 0 1; 0 1 0; 1 0 0];
            for i=1:1:length(X)
            text(app.UIAxes, i:i,Y(i)',num2str(Y(i)','%0.0f'),'HorizontalAlignment','center','VerticalAlignment','bottom','Color','w')
            end
        end

        % Button pushed function: PayloadButton
        function PayloadButtonPushed(app, event)
            %varaibles with Payloads are assigned to all satellites for that country
            c_temp = app.chinaYears(:,6);
            r_temp = app.russiaYears(:,6);
            u_temp = app.usYears(:,6);
            year = app.InitialSlider.Value;
            year2 = app.EndSlider.Value;
            c_pay = sum(c_temp >= year & c_temp <= year2); 
            r_pay = sum(r_temp >= year & r_temp <= year2); 
            u_pay = sum(u_temp >= year & u_temp <= year2);       
            app.PayloadTotal = c_pay + r_pay + u_pay;
            X = categorical({'China','Russia','US'});
            Y = [c_pay r_pay u_pay];
            b = bar(app.UIAxes, X, Y, 'FaceColor', 'flat');
            b.CData = [0 0 1; 0 1 0; 1 0 0];
            for i=1:1:length(X)
            text(app.UIAxes, i:i,Y(i)',num2str(Y(i)','%0.0f'),'HorizontalAlignment','center','VerticalAlignment','bottom', 'Color','w');
            end
        end

        % Value changed function: InitialSlider
        function InitialSliderValueChanged(app, event)
            value = app.InitialSlider.Value; %Top 
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            event.Source.Value = event.Source.MajorTicks(minIdx);
        end

        % Value changed function: EndSlider
        function EndSliderValueChanged(app, event)
            value = app.EndSlider.Value; %Bottom
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            event.Source.Value = event.Source.MajorTicks(minIdx);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %Axis 
            value = app.InitialSlider.Value; %Bottom
            value2 = app.EndSlider.Value;    %Top
            yeardiff = abs(value - value2);
            t1 = sprintf('%0.0f to %0.0f', value, value2);
            title(app.UIAxes2, t1, 'Color', 'w');
            %x axis: year range 
            app.STARTLIM = value;
            app.ENDLIM = value2; 
            %y axis: each element represents the total object type for that year
            x = app.STARTLIM:app.ENDLIM;
            %Debris
            c_temp = app.chinaYears(:,2);
            r_temp = app.russiaYears(:,2);
            u_temp = app.usYears(:,2);
            
            y_d = [];
            %if year is 1997
            if yeardiff == 0
                c_debris1 = sum(c_temp == value); 
                r_debris1 = sum(r_temp == value); 
                u_debris1 = sum(u_temp == value);
            else  %if year is not 1997
                for i = 1:yeardiff+1
                c_debris1 = sum(c_temp == value + (i-1)); 
                r_debris1 = sum(r_temp == value + (i-1)); 
                u_debris1 = sum(u_temp == value + (i-1));
                y_d(i) = c_debris1 + r_debris1 + u_debris1;
                app.tot_d = y_d(i);
                end
            end       
 
            %Rocket Body 
            c_temp2 = app.chinaYears(:,4);
            r_temp2 = app.russiaYears(:,4);
            u_temp2 = app.usYears(:,4);
            
            y_rb = [];
            if yeardiff == 0
                c_rb = sum(c_temp2 == value); 
                r_rb = sum(r_temp2 == value); 
                u_rb = sum(u_temp2 == value);
            else 
               for i = 1:yeardiff+1
                c_rb = sum(c_temp2 == value + (i-1)); 
                r_rb = sum(r_temp2 == value + (i-1)); 
                u_rb = sum(u_temp2 == value + (i-1));
                y_rb(i) = c_rb + r_rb + u_rb;
                app.tot_rb = y_rb(i);
                end
            end
  
            y_pay = [];
            %Payload 
            c_temp3 = app.chinaYears(:,6);
            r_temp3 = app.russiaYears(:,6);
            u_temp3 = app.usYears(:,6);
            
            if yeardiff == 0
                c_pay = sum(c_temp3 == value); 
                r_pay = sum(r_temp3 == value); 
                u_pay = sum(u_temp3 == value);
            else 
               for i = 1:yeardiff+1
                c_pay = sum(c_temp3 == value + (i-1)); 
                r_pay = sum(r_temp3 == value + (i-1)); 
                u_pay = sum(u_temp3 == value + (i-1));
                y_pay(i) = c_pay + r_pay + u_pay;
                app.tot_pay = y_pay(i);
                end
            end 
            
            app.total_objects = app.tot_pay + app.tot_rb + app.tot_d;
            
            app.UIAxes2.XTick = [value:2:value2];
            app.UIAxes2.XTickLabel = [value:2:value2];
            y_max = max([y_d, y_rb, y_pay]);
            y_index = round(y_max/yeardiff+1);
            app.UIAxes2.YTick = [0:y_index:y_max];

            %Plotting debris, rocketbody, and payload
            axis(app.UIAxes2,'tight')
            plot(app.UIAxes2, x, y_d, 'yellow');
            hold(app.UIAxes2,'on')
            plot(app.UIAxes2, x, y_rb, 'cyan');
            hold(app.UIAxes2,'on')
            plot(app.UIAxes2, x, y_pay, 'magenta');
            hold(app.UIAxes2,'off')
            
            
            %GLOBE
            LaunchYear = app.InitialSlider.Value;
            DecayYear = app.EndSlider.Value;
            
            
            u_deb_lau = app.DecayGlobeUS(:,1);
            u_deb_dec = app.DecayGlobeUS(:,2);
            u_rb_lau = app.DecayGlobeUS(:,3);
            u_rb_dec =app.DecayGlobeUS(:,4);
            u_pay_lau = app.DecayGlobeUS(:,5);
            u_pay_dec =app.DecayGlobeUS(:,6);
            
            r_deb_lau = app.DecayGlobeRussia(:,1);
            r_deb_dec = app.DecayGlobeRussia(:,2);
            r_rb_lau = app.DecayGlobeRussia(:,3);
            r_rb_dec =app.DecayGlobeRussia(:,4);
            r_pay_lau = app.DecayGlobeRussia(:,5);
            r_pay_dec =app.DecayGlobeRussia(:,6);

            c_deb_lau = app.DecayGlobeChina(:,1);
            c_deb_dec = app.DecayGlobeChina(:,2);
            c_rb_lau = app.DecayGlobeChina(:,3);
            c_rb_dec =app.DecayGlobeChina(:,4);
            c_pay_lau = app.DecayGlobeChina(:,5);
            c_pay_dec =app.DecayGlobeChina(:,6);
    

            intYear_udeb = u_deb_lau >= LaunchYear & u_deb_lau <= DecayYear;
            endYear_udeb = u_deb_dec >= LaunchYear & u_deb_dec <= DecayYear;
            diff_udeb = abs(sum(intYear_udeb,'all') - sum(endYear_udeb,'all'));

            intYear_urb = u_rb_lau >= LaunchYear & u_rb_lau <= DecayYear;
            endYear_urb = u_rb_dec >= LaunchYear & u_rb_dec <= DecayYear;
            diff_urb = abs(sum(intYear_urb,'all') - sum(endYear_urb,'all'));

            intYear_upay = u_pay_lau >= LaunchYear & u_pay_lau <= DecayYear;
            endYear_upay = u_pay_dec >= LaunchYear & u_pay_dec <= DecayYear;
            diff_upay = abs(sum(intYear_upay,'all') - sum(endYear_upay,'all'));


            usTotal = diff_udeb + diff_urb + diff_upay;

            
            intYear_rdeb = r_deb_lau >= LaunchYear & r_deb_lau <= DecayYear;
            endYear_rdeb = r_deb_dec >= LaunchYear & r_deb_dec <= DecayYear;
            diff_rdeb = abs(sum(intYear_rdeb,'all') - sum(endYear_rdeb,'all'));

            intYear_rrb = r_rb_lau >= LaunchYear & r_rb_lau <= DecayYear;
            endYear_rrb = r_rb_dec >= LaunchYear & r_rb_dec <= DecayYear;
            diff_rrb = abs(sum(intYear_rrb,'all') - sum(endYear_rrb,'all'));

            intYear_rpay = r_pay_lau >= LaunchYear & r_pay_lau <= DecayYear;
            endYear_rpay = r_pay_dec >= LaunchYear & r_pay_dec <= DecayYear;
            diff_rpay = abs(sum(intYear_rpay,'all') - sum(endYear_rpay,'all'));


            russiaTotal = diff_rdeb + diff_rrb + diff_rpay;


            intYear_cdeb = c_deb_lau >= LaunchYear & c_deb_lau <= DecayYear;
            endYear_cdeb = c_deb_dec >= LaunchYear & c_deb_dec <= DecayYear;
            diff_cdeb = abs(sum(intYear_cdeb,'all') - sum(endYear_cdeb,'all'));

            intYear_crb = c_rb_lau >= LaunchYear & c_rb_lau <= DecayYear;
            endYear_crb = c_rb_dec >= LaunchYear & c_rb_dec <= DecayYear;
            diff_crb = abs(sum(intYear_crb,'all') - sum(endYear_crb,'all'));

            intYear_cpay = c_pay_lau >= LaunchYear & c_pay_lau <= DecayYear;
            endYear_cpay = c_pay_dec >= LaunchYear & c_pay_dec <= DecayYear;
            diff_cpay = abs(sum(intYear_cpay,'all') - sum(endYear_cpay,'all'));


            chinaTotal = diff_cdeb + diff_crb + diff_cpay;


            set(app.GlobeAxes,'Color','none');
            
            [X,Y,Z] = sphere(100);
            a = [1 1 1 8];
            globe = surf(app.GlobeAxes,X*a(1,4),Y*a(1,4),Z*a(1,4));
            image = imread('GLOBE.jpeg');
            set(globe, 'FaceColor','texturemap','EdgeColor','none','Cdata',flipud(image));
            
            hold(app.GlobeAxes,'on')

            numPoints = usTotal;
            radius = 10;
           
            r1 = randn(3, numPoints);
            r1 = bsxfun(@rdivide, r1, sqrt(sum(r1.^2,1)));
            r1 = radius * r1;
            
            x = r1(1,:);
            y = r1(2,:);
            z = r1(3,:); 
            
            scatter3(app.GlobeAxes,x,y,z,6,'red','filled');

            hold(app.GlobeAxes,'on')

            numPoints2 = russiaTotal;
            radius2 = 10;
           
            r2 = randn(3, numPoints2);
            r2 = bsxfun(@rdivide, r2, sqrt(sum(r2.^2,1)));
            r2 = radius2 * r2;
            
            xx = r2(1,:);
            yy = r2(2,:);
            zz = r2(3,:);
            
            scatter3(app.GlobeAxes,xx,yy,zz,6,'green','filled');

            hold(app.GlobeAxes,'on')

            numPoints3 = chinaTotal;
            radius3 = 10;
           
            r3 = randn(3, numPoints3);
            r3 = bsxfun(@rdivide, r3, sqrt(sum(r3.^2,1)));
            r3 = radius3 * r3;
            
            xxx = r3(1,:);
            yyy = r3(2,:);
            zzz = r3(3,:); 
            
            scatter3(app.GlobeAxes,xxx,yyy,zzz,6,'blue','filled');

            hold(app.GlobeAxes,'on')

            numPoints4 = app.total_objects * 15;
            radius4 = 10;
           
            r4 = randn(3, numPoints4);
            r4 = bsxfun(@rdivide, r4, sqrt(sum(r4.^2,1)));
            r4 = radius4 * r4;
            
            xxxx = r4(1,:);
            yyyy = r4(2,:);
            zzzz = r4(3,:); 
            
            scatter3(app.GlobeAxes,xxxx,yyyy,zzzz,0.35,[1 1 1],'filled');

            hold(app.GlobeAxes,'off')

            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            app.HelpTextArea.Visible="on";
            app.HelpButton_2.Visible="on";
            app.HelpButton.Visible="off";
        end

        % Button pushed function: HelpButton_2
        function HelpButton_2Pushed(app, event)
            app.HelpTextArea.Visible="off";
            app.HelpButton_2.Visible="off";
            app.HelpButton.Visible="on";
        end

        % Button pushed function: SendMetoAppButton
        function SendMetoAppButtonPushed(app, event)
            app.DebrisButton.Visible="on";
            app.HelpTextArea.Visible="off";
            app.PlotButton.Visible="on";
            app.RocketBodyButton.Visible="on";
            app.InitialSlider.Visible="on";
            app.PayloadButton.Visible="on";
            app.GlobeAxes.Visible="on";
            app.UIAxes.Visible="on";
            app.UIAxes2.Visible="on";
            app.InitialSliderLabel.Visible="on";
            app.EndSlider.Visible="on";
            app.EndSliderLabel.Visible="on";
            app.HelpButton.Visible="on";
            app.TextArea.Visible="off";
            %app.Label_3.Visible = "on";
            %app.Label.Visible = "on";
            %app.Label_2.Visible = "on";
            app.Label_4.Visible = "on";
            app.Label_5.Visible = "on";
            app.Label_6.Visible = "on";
            app.EditField_4.Visible = "on";
            app.EditField_5.Visible = "on";
            app.EditField_6.Visible = "on";
            app.EditField_10.Visible = "on";
            app.EditField_11.Visible = "on";
            app.EditField_12.Visible = "on";
            app.TotalDEB.Visible = "on";
            app.TotalRB.Visible = "on";
            app.TotalPAY.Visible = "on";
            app.SendMetoAppButton.Visible="off";
            app.SpaceDebrisandObjectsinLEOLabel.Visible="off";
            app.ByLabel.Visible="off";
            app.Image.Visible='off';
            app.HelpTextArea.Value="Step 1: Move the sliders to select a range of years. ";
            app.HelpTextArea.Value=[app.HelpTextArea.Value;"Step 2: Click the Plot button."];
            app.HelpTextArea.Value=[app.HelpTextArea.Value;"Step 3: Choose between 'payload', 'Rocket Body', or 'Debris' buttons to see the different graphs."];
            app.PlotButtonPushed
            app.DebrisButtonPushed
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes2, 'Number of Objects in LEO ')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.UIAxes = uiaxes(app.UIFigure);
            ylabel(app.UIAxes, 'Object count')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 14];
            app.UIAxes.XTick = [3 7 11];
            app.UIAxes.XTickLabel = {'China'; 'Russia'; 'USA'};
            app.UIAxes.YTick = [];
            app.GlobeAxes = uiaxes(app.UIFigure);
            app.GlobeAxes.XTick = [];
            app.GlobeAxes.YTick = [];
            app.GlobeAxes.ZTick = [];
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'space2.jpg');
            app.DebrisButton = uibutton(app.UIFigure, 'push');
            app.DebrisButton.ButtonPushedFcn = createCallbackFcn(app, @DebrisButtonPushed, true);
            app.DebrisButton.Text = 'Debris';
            app.RocketBodyButton = uibutton(app.UIFigure, 'push');
            app.RocketBodyButton.ButtonPushedFcn = createCallbackFcn(app, @RocketBodyButtonPushed, true);
            app.RocketBodyButton.Text = 'Rocket Body';
            app.PayloadButton = uibutton(app.UIFigure, 'push');
            app.PayloadButton.ButtonPushedFcn = createCallbackFcn(app, @PayloadButtonPushed, true);
            app.PayloadButton.Text = 'Payload';
            app.InitialSliderLabel = uilabel(app.UIFigure);
            app.InitialSliderLabel.Text = 'Initial ';
            app.InitialSlider = uislider(app.UIFigure);
            app.InitialSlider.Limits = [1997 2022];
            app.InitialSlider.MajorTicks = [1997 1998 1999 2000 2001 2002 2003 2004 2005 2006 2007 2008 2009 2010 2011 2012 2013 2014 2015 2016 2017 2018 2019 2020 2021 2022];
            app.InitialSlider.ValueChangedFcn = createCallbackFcn(app, @InitialSliderValueChanged, true);
            app.InitialSlider.MinorTicks = 1;
            app.InitialSlider.Value = 1997;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.EndSliderLabel = uilabel(app.UIFigure);
            app.EndSliderLabel.Text = 'End';
            app.EndSlider = uislider(app.UIFigure);
            app.EndSlider.Limits = [1997 2022];
            app.EndSlider.MajorTicks = [1997 1998 1999 2000 2001 2002 2003 2004 2005 2006 2007 2008 2009 2010 2011 2012 2013 2014 2015 2016 2017 2018 2019 2020 2021 2022];
            app.EndSlider.ValueChangedFcn = createCallbackFcn(app, @EndSliderValueChanged, true);
            app.EndSlider.MinorTicks = 1;
            app.EndSlider.Value = 1997;
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.HelpButton_2 = uibutton(app.UIFigure, 'push');
            app.HelpButton_2.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_2Pushed, true);
            app.HelpButton_2.Text = 'Help';
            app.HelpTextArea = uitextarea(app.UIFigure);
            app.SendMetoAppButton = uibutton(app.UIFigure, 'push');
            app.SendMetoAppButton.ButtonPushedFcn = createCallbackFcn(app, @SendMetoAppButtonPushed, true);
            app.SendMetoAppButton.Text = 'Send Me to App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TotalPAY = uilabel(app.UIFigure);
            app.TotalPAY.Text = '';
            app.TotalDEB = uilabel(app.UIFigure);
            app.TotalDEB.Text = '';
            app.TotalRB = uilabel(app.UIFigure);
            app.TotalRB.Text = '';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.Value = 'Debris';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_5.Value = 'Rocket Body';
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.EditField_6.Value = 'Payload';
            app.EditField_10 = uieditfield(app.UIFigure, 'text');
            app.EditField_10.Value = 'US';
            app.EditField_11 = uieditfield(app.UIFigure, 'text');
            app.EditField_11.Value = 'China';
            app.EditField_12 = uieditfield(app.UIFigure, 'text');
            app.EditField_12.Value = 'Russia';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.SpaceDebrisandObjectsinLEOLabel = uilabel(app.UIFigure);
            app.SpaceDebrisandObjectsinLEOLabel.Text = 'Space Debris and Objects in LEO';
            app.ByLabel = uilabel(app.UIFigure);
            app.ByLabel.Text = 'Brian Alino, Meena Al Hasani, Gregory Maddox, Vedant Patel, Jessica Semaan, and Kuday Yurter ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NAEGroup5_Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end